classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExtractedtextTextArea       matlab.ui.control.TextArea
        ExtractedtextTextAreaLabel  matlab.ui.control.Label
        GetDataButton               matlab.ui.control.Button
        CaptureButton               matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        img %image to be processed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.png;*.jpg;*.jpeg')  %get a png or jpg file
            fullFileName = fullfile(path, file);
            app.img = imread(fullFileName);
            [x,y, numberOfColorChannels] = size(app.img)
            if (contains(file, ".jpg") == 1  || contains(file, ".jpeg") == 1) && x > 2000 && y > 2000
                app.img = imrotate(app.img, -90);
            end
            %if image is grayscale, convert to color
            if numberOfColorChannels <= 1
                app.img = cat(3,app.img,app.img,app.img);
            end
            app.Image.ImageSource = app.img;
            %refocus app
            figure(app.UIFigure);
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            cam = webcam;
            app.img = snapshot(cam);
            app.Image.ImageSource = app.img;
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            %prompt user to wait for processing
            h = msgbox('Please wait...');
            %TODO check to make sure image is selected or captured
            
            %preprocessing
            processedImg = preprocess(app.img);
            %put grayscale image back to m x n x 3 format
            RGB = cat(3,processedImg,processedImg,processedImg);
            app.Image.ImageSource = RGB;
            %text reading
            cellArrayText = performocr(processedImg);
            %post process text
            finalString = postprocess(cellArrayText);
            %put data in text area
            app.ExtractedtextTextArea.Value = (finalString);
            close(h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.ExtractedtextTextAreaLabel = uilabel(app.UIFigure);
            app.ExtractedtextTextAreaLabel.Text = 'Extracted text';
            app.ExtractedtextTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end